classdef ME273_GUI_2438 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TakeoffAngleKnob               matlab.ui.control.Knob
        TakeoffAngleKnobLabel          matlab.ui.control.Label
        KicktheBallButton              matlab.ui.control.Button
        HeadwindVelocitymphSlider      matlab.ui.control.Slider
        HeadwindVelocitymphLabel       matlab.ui.control.Label
        ConsistencySlider              matlab.ui.control.Slider
        ConsistencySliderLabel         matlab.ui.control.Label
        KickingVelocitymphSlider       matlab.ui.control.Slider
        KickingVelocitymphSliderLabel  matlab.ui.control.Label
        DistancetoUprightsyardsSlider  matlab.ui.control.Slider
        DistancetoUprightsyardsSliderLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        UIAxes                         matlab.ui.control.UIAxes
    end

        %% Input manual variables here:
    properties (Access = private)
        d2gy = 20; %distance to goal (yards)
        Consistency = 90; %Likelyhood of making a Point After Touchdown (20 yd field goal)
        v0 = 50; %initial velocity (mph)
        degrees = 45; %takeoff angle
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: KicktheBallButton
        function KicktheBallButtonPushed(app, event)
              t = 0:0.2:10; %Set time to Record Data
    %For Manual Input:
        %d2gy = app.d2gy;
        %Consistency = app.Consistency;
        %v0 = app.v0;
        %degrees = app.degrees;
                         
    % For Slider Inputs          
v0 = app.KickingVelocitymphSlider.Value; %mph
d2gy = app.DistancetoUprightsyardsSlider.Value;
Consistency = app.ConsistencySlider.Value; %angle and velocity consistency
degrees = app.TakeoffAngleKnob.Value; %Set launch angle
wind = app.HeadwindVelocitymphSlider.Value;

Cons = Consistency; %Simplify Variable Name
v0yps = v0*0.488889; %yards per second
%realdeg = degrees + normrnd(0,degrees*(100-Cons)/100,1); %Degrees with variation
realdeg = degrees + randn(1,1)*degrees*(100-Cons)/100;
%noiserad = normrnd(0,(100-Cons)/100,1)*pi/4.5; %Give z-axis error
noiserad = randn(1,1)*(100-Cons)*pi/450
theta = realdeg*pi/180; %Give real launch angle
drag = wind .* 0.5
%realv0 = v0yps + normrnd(-v0yps*(100-Cons)/180,v0yps*(100-Cons)/200,1); %Give Real launch velocity
realv0 = v0yps + (-v0yps*randn(1,1)*(100-Cons)/190)


%Modeling Uprights
y1(1:2) = 3.3333; %Crossbarr
z1(1) = -3.1; z1(2)=3.1;
x1(1:2) = d2gy;
x2(1:2) = d2gy;
y2(1) = 3.3333; y2(2) = 20;
z2(1:2) = - 3.1;
z3(1:2) = 3.1;
%Plotting Uprights
hold (app.UIAxes,"off");
plot3(app.UIAxes,x1,z1,y1, "color", [0.9290 0.6940 0.1250], 'LineWidth',3)
hold (app.UIAxes,"on")
plot3(app.UIAxes,x2,z2,y2, "color", [0.9290 0.6940 0.1250], 'LineWidth',3);
plot3(app.UIAxes,x2,z3,y2 , "color", [0.9290 0.6940 0.250], 'LineWidth',3);


%Plotting Uprights Base
x4 = d2gy:d2gy+2;
x4(4) = d2gy+2;
z4(1:4) = 0;
y4(1:2) = 3.3333;
y4(3) = 2.8333;
y4(4) = 0;
plot3(app.UIAxes,x4,z4,y4, "color", [0.9290 0.6940 0.250], 'LineWidth',3);

%Plotting turf surface
[surfx surfz] = meshgrid(-20:5:d2gy+30); % Generate x and y data
surfy = zeros(size(surfx, 1)); % Generate z data
surf(app.UIAxes,surfx, surfz, surfy, 'EdgeColor', [0.0547 0.5 0.203], "FaceColor", [0.0547 0.5 0.203])

%Creating path of ball
for i=1:length(t)
y(i) = realv0 * t(i) * sin(theta) - 10.725 * (t(i)^2)/2;
x(i) = realv0 * t(i) * cos(theta) - (wind+realv0)*.04*t(i)^2;
z(i) = x(i) * (noiserad);
end

%Plotting Trajectory as red
plot3(app.UIAxes,x,z,y, "-r", 'LineWidth',2)

%Limiting view so the field looks nice
xlim(app.UIAxes,[0 (d2gy+10)])
ylim(app.UIAxes,[-18 18])
zlim(app.UIAxes,[0 40])


%%%%%%%%%%%%%%%%%%%%%%%%%%%

guess = [0.01, 5];
tol = .001;
xl = guess(1); %arrange xl value
xu = guess(2);%arrange xu value

error = 1; %set dummy error to 1%
i=0; %set index
xr= 0.01; %set dummy xr value

while error > tol 
    %Do equation
    if i < 100
        xold = xr; %replace xold value
        xr = (xl + xu)/2; %change xr
error = abs((xr - xold) / xr)*100 %calculate error
    end
%Find y-values for necessary 
fxl = realv0 * xl * cos(theta) - (wind+realv0)*.04*xl^2;
fxr = realv0 * xr * cos(theta) - (wind+realv0)*.04*xr^2;
fxu = realv0 * xu * cos(theta) - (wind+realv0)*.04*xu^2;

%Replace xl or xu
    if (fxl-d2gy)*(fxr-d2gy) < 0 %if fxl, fxr are opposite in sign
        xu = xr; %replace xu with xr
    end

     if (fxl-d2gy)*(fxr-d2gy) > 0 %if fxl is the same sign as xr
        xl = xr %replace sl with xr
     end


i = i+1; %update index
% If I get a 0, that means I found a root...
    if (fxl-d2gy)*(fxr-d2gy) == 0 %if you're spot on, break
        break;
    end
     if (fxu-d2gy)*(fxr-d2gy) == 0 %if you're spot on, break
        break;
    end

%Error code
        if i >= 100 %if you've tried 100 times, write an error
        error("Could not find root value within 100 tries");
        end
  
%Check if I need to be done
    if error < tol %if you've suceeded, finish strong
        break;
    end

end
    yxr = realv0 * xr * sin(theta) - 10.725 * (xr^2)/2; 
    if yxr > 10
        app.Lamp.Color = "g"
    end

    yxr = realv0 * xr * sin(theta) - 10.725 * (xr^2)/2; 
    zxr = fxr * (noiserad);
    if yxr > 3.33333
        if abs(zxr) < 3.0833
             app.Lamp.Color = "g"
             app.Label.Text = "IT'S GOOD!"
        end
        if abs(zxr) > 3.0833
            app.Lamp.Color = "r"
            app.Label.Text = "YOU MISSED!"
        end
    end
        if yxr <= 3.3333
        app.Lamp.Color = "r"
        app.Label.Text = "YOU MISSED!"
    end

        %%%%%%%%%%%%%%%%%%%%%%%%
        
        
        end

        % Value changed function: ConsistencySlider
        function ConsistencySliderValueChanged(app, event)

            
        end

        % Value changed function: DistancetoUprightsyardsSlider
        function DistancetoUprightsyardsSliderValueChanged(app, event)

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Trajectory')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Lamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {''; ''};
            app.DistancetoUprightsyardsSliderLabel = uilabel(app.UIFigure);
            app.DistancetoUprightsyardsSliderLabel.Text = 'Distance to Uprights (yards)';
            app.DistancetoUprightsyardsSlider = uislider(app.UIFigure);
            app.DistancetoUprightsyardsSlider.Limits = [10 100];
            app.DistancetoUprightsyardsSlider.MajorTicks = [10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 100];
            app.DistancetoUprightsyardsSlider.MajorTickLabels = {'10', '', '20', '', '30', '', '40', '', '50', '', '60', '', '70', '', '80', '', '90', '100'};
            app.DistancetoUprightsyardsSlider.ValueChangedFcn = createCallbackFcn(app, @DistancetoUprightsyardsSliderValueChanged, true);
            app.DistancetoUprightsyardsSlider.MinorTicks = [15 25 35 45 55 65 75 85 95];
            app.DistancetoUprightsyardsSlider.Value = 10;
            app.KickingVelocitymphSliderLabel = uilabel(app.UIFigure);
            app.KickingVelocitymphSliderLabel.Text = 'Kicking Velocity (mph)';
            app.KickingVelocitymphSlider = uislider(app.UIFigure);
            app.ConsistencySliderLabel = uilabel(app.UIFigure);
            app.ConsistencySliderLabel.Text = 'Consistency (%)';
            app.ConsistencySlider = uislider(app.UIFigure);
            app.ConsistencySlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.ConsistencySlider.ValueChangedFcn = createCallbackFcn(app, @ConsistencySliderValueChanged, true);
            app.ConsistencySlider.Value = 50;
            app.HeadwindVelocitymphLabel = uilabel(app.UIFigure);
            app.HeadwindVelocitymphLabel.Text = 'Headwind Velocity (mph)';
            app.HeadwindVelocitymphSlider = uislider(app.UIFigure);
            app.HeadwindVelocitymphSlider.Limits = [-40 40];
            app.HeadwindVelocitymphSlider.MajorTicks = [-40 -20 0 20 40];
            app.HeadwindVelocitymphSlider.MinorTicks = [-40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40];
            app.KicktheBallButton = uibutton(app.UIFigure, 'push');
            app.KicktheBallButton.ButtonPushedFcn = createCallbackFcn(app, @KicktheBallButtonPushed, true);
            app.KicktheBallButton.Text = '      Kick the              Ball';
            app.TakeoffAngleKnobLabel = uilabel(app.UIFigure);
            app.TakeoffAngleKnobLabel.Text = 'Takeoff Angle';
            app.TakeoffAngleKnob = uiknob(app.UIFigure, 'continuous');
            app.TakeoffAngleKnob.Limits = [0 90];
            app.TakeoffAngleKnob.MajorTicks = [0 10 20 30 40 50 60 70 80 90];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ME273_GUI_2438

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end